classdef FiberTester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField_950        matlab.ui.control.NumericEditField
        shutteropenmsEditFieldLabel     matlab.ui.control.Label
        shutteropenmsEditField_950      matlab.ui.control.NumericEditField
        stimdurationmsEditFieldLabel    matlab.ui.control.Label
        stimdurationmsEditField_950     matlab.ui.control.NumericEditField
        InitializeButton                matlab.ui.control.Button
        enable_950Button                matlab.ui.control.StateButton
        enable_1100Button               matlab.ui.control.StateButton
        delaymsEditFieldLabel           matlab.ui.control.Label
        delaymsEditField_950            matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label     matlab.ui.control.Label
        FrequencyHzEditField_1100       matlab.ui.control.NumericEditField
        shutteropenmsEditField_2Label   matlab.ui.control.Label
        shutteropenmsEditField_1100     matlab.ui.control.NumericEditField
        stimdurationmsEditField_2Label  matlab.ui.control.Label
        stimdurationmsEditField_1100    matlab.ui.control.NumericEditField
        delaymsEditField_2Label         matlab.ui.control.Label
        delaymsEditField_1100           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        FIREButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tman % Description
        
        parameters_950
        parameters_1100
        
        enable_950
        enable_1100
    end
    
    methods (Access = private)
        
        function set_trigger(app, target, parameters);
            % unpack
            delay = parameters.delay;
            ms_on = parameters.msec_on;
            ms_off = parameters.msec_off;
            t = delay;
            while t < parameters.stim_duration
                target.set(t, ms_on, 1);
                t = t + ms_on + ms_off;
            end
        end
        
        function [p1, p2] = initialize_params(app)
            p1.freq = 10;
            p1.msec_on = 10;
            p1.delay = 0;
            p1.stim_duration = 1000;
            p2 = p1;
        end
        
        function plot_sweeps(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%
            fprintf('Starting daq...\r')
            
            fprintf('Loading defaults... ')
            setup = getDefaults();
            fprintf('OK.\n')
            
            fprintf('Making MATLAB NIDAQ object... ')
            dq = fakedaq;%daq('ni');
            dq.Rate = setup.daqrate;
            fprintf('OK.\n')
            app.tman = TrialManager(dq);
            
            output_laser_950 = Output(DAQOutput(dq, 'port0/line5'), '900 Laser');
            output_laser_1100 = Output(DAQOutput(dq, 'port0/line6'), '1100 Laser');
            si_trigger = Output(DAQOutput(dq, 'port0/line0'), 'SI Trigger');
            
            ft = FiberTester(output_laser_950, output_laser_1100, si_trigger, laser_eom);
            
            app.tman.modules.add(ft);
%             app.tman.set_save_path('test')
            [app.parameters_950, app.parameters_1100] = app.initialize_params();
            
            % Initialize modules
            app.tman.initialize(); % initialize all added modules
            
        end

        % Value changed function: FrequencyHzEditField_950
        function FrequencyHzEditField_950ValueChanged(app, event)
            app.parameters_950.freq = app.FrequencyHzEditField_950.Value;
        end

        % Value changed function: shutteropenmsEditField_950
        function shutteropenmsEditField_950ValueChanged(app, event)
            app.parameters_950.msec_on = app.shutteropenmsEditField_950.Value;
            msec_off = 1000/app.parameters_950.freq - app.parameters_950.msec_on;
            if msec_off < 0
                error('MSEC ON TOO LONG')
            end
            app.parameters_950.msec_off = msec_off;
        end

        % Value changed function: stimdurationmsEditField_950
        function stimdurationmsEditField_950ValueChanged(app, event)
            app.parameters_950.stim_duration = app.stimdurationmsEditField_950.Value;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.tman.set_trial_length(max(app.parameters_950.stim_duration + app.parameters_950.delay, app.parameters_1100.stim_duration + app.parameters_1100.delay));
         
            % calculate all the things here
            if app.enable_950
                app.set_trigger(app.tman.modules.output_laser_950, app.parameters_950);
            end       
            
            if app.enable_1100
                app.set_trigger(app.tman.modules.output_laser_1000, app.parameters_1100);
            end
            
            app.tman.prepare();
            app.plot_sweeps();
            
        end

        % Value changed function: enable_950Button
        function enable_950ButtonValueChanged(app, event)
            app.enable_950 = app.enable_950Button.Value;
        end

        % Value changed function: enable_1100Button
        function enable_1100ButtonValueChanged(app, event)
            app.enable_1100 = app.enable_1100Button.Value;
        end

        % Value changed function: FrequencyHzEditField_1100
        function FrequencyHzEditField_1100ValueChanged(app, event)
            app.parameters_1100.freq = app.FrequencyHzEditField_1100.Value;
        end

        % Value changed function: shutteropenmsEditField_1100
        function shutteropenmsEditField_1100ValueChanged(app, event)
            app.parameters_1100.msec_on = app.shutteropenmsEditField_1100.Value;
            msec_off = 1000/app.parameters_1100.freq - app.parameters_1100.msec_on;
            if msec_off < 0
                error('MSEC ON TOO LONG')
            end
            app.parameters_1100.msec_off = msec_off;
        end

        % Value changed function: stimdurationmsEditField_1100
        function stimdurationmsEditField_1100ValueChanged(app, event)
            app.parameters_1100.stim_duration = app.stimdurationmsEditField_1100.Value;
        end

        % Value changed function: delaymsEditField_1100
        function delaymsEditField_1100ValueChanged(app, event)
            app.parameters_1100.delay = app.delaymsEditField_1100.Value;
        end

        % Value changed function: delaymsEditField_950
        function delaymsEditField_950ValueChanged(app, event)
            app.parameters_950.delay = app.delaymsEditField_950.Value;
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            app.tman.start_trial();
            app.tman.end_trial();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField_950.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditField_950ValueChanged, true);
            app.FrequencyHzEditField_950.Value = 10;
            app.shutteropenmsEditFieldLabel = uilabel(app.UIFigure);
            app.shutteropenmsEditFieldLabel.Text = 'shutter open (ms)';
            app.shutteropenmsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.shutteropenmsEditField_950.ValueChangedFcn = createCallbackFcn(app, @shutteropenmsEditField_950ValueChanged, true);
            app.shutteropenmsEditField_950.Value = 10;
            app.stimdurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.stimdurationmsEditFieldLabel.Text = 'stim duration (ms)';
            app.stimdurationmsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.stimdurationmsEditField_950.ValueChangedFcn = createCallbackFcn(app, @stimdurationmsEditField_950ValueChanged, true);
            app.stimdurationmsEditField_950.Value = 1000;
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.enable_950Button = uibutton(app.UIFigure, 'state');
            app.enable_950Button.ValueChangedFcn = createCallbackFcn(app, @enable_950ButtonValueChanged, true);
            app.enable_950Button.Text = 'enable';
            app.enable_1100Button = uibutton(app.UIFigure, 'state');
            app.enable_1100Button.ValueChangedFcn = createCallbackFcn(app, @enable_1100ButtonValueChanged, true);
            app.enable_1100Button.Text = 'enable';
            app.delaymsEditFieldLabel = uilabel(app.UIFigure);
            app.delaymsEditFieldLabel.Text = 'delay (ms)';
            app.delaymsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.delaymsEditField_950.ValueChangedFcn = createCallbackFcn(app, @delaymsEditField_950ValueChanged, true);
            app.FrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.FrequencyHzEditField_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField_1100.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditField_1100ValueChanged, true);
            app.FrequencyHzEditField_1100.Value = 10;
            app.shutteropenmsEditField_2Label = uilabel(app.UIFigure);
            app.shutteropenmsEditField_2Label.Text = 'shutter open (ms)';
            app.shutteropenmsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.shutteropenmsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @shutteropenmsEditField_1100ValueChanged, true);
            app.shutteropenmsEditField_1100.Value = 10;
            app.stimdurationmsEditField_2Label = uilabel(app.UIFigure);
            app.stimdurationmsEditField_2Label.Text = 'stim duration (ms)';
            app.stimdurationmsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.stimdurationmsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @stimdurationmsEditField_1100ValueChanged, true);
            app.stimdurationmsEditField_1100.Value = 1000;
            app.delaymsEditField_2Label = uilabel(app.UIFigure);
            app.delaymsEditField_2Label.Text = 'delay (ms)';
            app.delaymsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.delaymsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @delaymsEditField_1100ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '950';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1100';
            app.FIREButton = uibutton(app.UIFigure, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberTester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end