classdef FiberTesterAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        polarizer_1100                  matlab.ui.control.Spinner
        PolarizerdegSpinner_2Label      matlab.ui.control.Label
        polarizer_950                   matlab.ui.control.Spinner
        PolarizerdegSpinnerLabel        matlab.ui.control.Label
        shutter_1100                    matlab.ui.control.ToggleSwitch
        shutter_950                     matlab.ui.control.ToggleSwitch
        FIREButton                      matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        delaymsEditField_1100           matlab.ui.control.NumericEditField
        delaymsEditField_2Label         matlab.ui.control.Label
        stimdurationmsEditField_1100    matlab.ui.control.NumericEditField
        stimdurationmsEditField_2Label  matlab.ui.control.Label
        shutteropenmsEditField_1100     matlab.ui.control.NumericEditField
        shutteropenmsEditField_2Label   matlab.ui.control.Label
        FrequencyHzEditField_1100       matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label     matlab.ui.control.Label
        delaymsEditField_950            matlab.ui.control.NumericEditField
        delaymsEditFieldLabel           matlab.ui.control.Label
        enable_1100Button               matlab.ui.control.StateButton
        enable_950Button                matlab.ui.control.StateButton
        InitializeButton                matlab.ui.control.Button
        stimdurationmsEditField_950     matlab.ui.control.NumericEditField
        stimdurationmsEditFieldLabel    matlab.ui.control.Label
        shutteropenmsEditField_950      matlab.ui.control.NumericEditField
        shutteropenmsEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField_950        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        ShutterparametersPanel          matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tman % Description
        
        parameters_950
        parameters_1100
        
        enable_950
        enable_1100

        state_950 = false;
        state_1100 = false;
    end
    
    methods (Access = private)
        
        function set_trigger(app, target, parameters)
            % unpack
            delay = parameters.delay;
            ms_on = parameters.msec_on;
            ms_off = parameters.msec_off;
            t = delay + 1;
            while t < parameters.stim_duration + delay
                target.io.set([t, ms_on, 1]);
                t = t + ms_on + ms_off;
            end
        end
        
        function [p1, p2] = initialize_params(app)
            p1.freq = 10;
            p1.msec_on = 10;
            p1.msec_off = 90;
            p1.delay = 0;
            p1.stim_duration = 1000;
            p2 = p1;
        end
        
        function plot_sweeps(app)
            sweep_920 = app.tman.modules.FiberPowerControl.shutter.io.generate_sweep();
            sweep_1100 = app.tman.modules.FiberPowerControl_1.shutter.io.generate_sweep();
            hold(app.UIAxes, 'off') 
            plot(app.UIAxes, (1:length(sweep_920))/app.tman.dq.Rate, sweep_920);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, (1:length(sweep_1100))/app.tman.dq.Rate, sweep_1100);
            title(app.UIAxes, []);
            legend(app.UIAxes, {'950', '1100'});
            ylabel(app.UIAxes, 'trigger');
            xlabel(app.UIAxes, 'time (ms)')

        end
        
        function params = calculate_msec_off(app, params)
            msec_off = 1000/params.freq - params.msec_on;
            if msec_off < 0
                error('MSEC ON TOO LONG')
            end
            params.msec_off = msec_off;
        end
        
        function update_shutters(app)
            app.tman.dq.write([app.state_950, app.state_1100]);
            if app.state_950
                app.shutter_950.Value = 'OPEN';
            else
                app.shutter_950.Value = 'CLOSED';
            end

            if app.state_1100
                app.shutter_1100.Value = 'OPEN';
            else
                app.shutter_1100.Value = 'CLOSED';
            end
        end
        
        function close_shutters(app)
            app.state_1100 = false;
            app.state_950 = false;
            app.update_shutters();
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%
            fprintf('Starting daq...\r')
            
            fprintf('Loading defaults... ')
            setup = getDefaults();
            fprintf('OK.\n')
            
            fprintf('Making MATLAB NIDAQ object... ')
            dq = daq('ni');
            dq.Rate = setup.daqrate;
            fprintf('OK.\n')

            s = serialport("COM5", ...
                9600,...
                'ByteOrder', 'big-endian',...
                'Parity', 'none',...
                'StopBits', 1,...
                'DataBits', 8);
            s.configureTerminator('CR/LF');


            app.tman = TrialManager(dq);
            
%             clock = Output(DAQOutput(dq, 'ao0'), 'Clock');
            sh_950 = Output(DAQOutput(dq, 'port0/line5'), 'Laser 950');
            sh_1100 = Output(DAQOutput(dq, 'port0/line6'), 'Laser 1100');
            
            hwp_950 = ELL14(SerialInterface(s), 0, 'hwp1');
            hwp_1100 = ELL14(SerialInterface(s), 1, 'hwp2');
            
            dq.addinput('Dev1', 'ai0', 'Voltage');
%         si_trigger = Output(DAQOutput(dq, 'port0/line0'), 'SI Trigger');
            
%         ft = FiberTester(output_laser_950, output_laser_1100, si_trigger, clock);
            fpc_950 = FiberPowerControl(sh_950, hwp_950, []); % change later so that we can have multiple moduesl
            fpc_1100 = FiberPowerControl(sh_1100, hwp_1100, []);

            
            app.tman.modules.add(fpc_950);
            app.tman.modules.add(fpc_1100);
            app.tman.set_save_path('D:/data/dump')
            [app.parameters_950, app.parameters_1100] = app.initialize_params();
            
            % Initialize modules
            app.tman.initialize(); % initialize all added modules
            disableDefaultInteractivity(app.UIAxes);
            
        end

        % Value changed function: FrequencyHzEditField_950
        function FrequencyHzEditField_950ValueChanged(app, event)
            app.parameters_950.freq = app.FrequencyHzEditField_950.Value;
        end

        % Value changed function: shutteropenmsEditField_950
        function shutteropenmsEditField_950ValueChanged(app, event)
            app.parameters_950.msec_on = app.shutteropenmsEditField_950.Value;
           
        end

        % Value changed function: stimdurationmsEditField_950
        function stimdurationmsEditField_950ValueChanged(app, event)
            app.parameters_950.stim_duration = app.stimdurationmsEditField_950.Value;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.close_shutters();
            app.shutter_950.Enable = false;
            app.shutter_1100.Enable = false;

            app.tman.cleanup();

            app.parameters_950 = app.calculate_msec_off(app.parameters_950);
            app.parameters_1100 = app.calculate_msec_off(app.parameters_1100);

            app.tman.set_trial_length(max(app.parameters_950.stim_duration + app.parameters_950.delay, app.parameters_1100.stim_duration + app.parameters_1100.delay));
         
            % calculate all the things here
            if app.enable_950
                app.set_trigger(app.tman.modules.FiberPowerControl.shutter, app.parameters_950);
            end       
            
            if app.enable_1100
                app.set_trigger(app.tman.modules.FiberPowerControl_1.shutter, app.parameters_1100);
            end
            
            app.tman.prepare();
            app.plot_sweeps();
            app.FIREButton.Enable = "on";
            
        end

        % Value changed function: enable_950Button
        function enable_950ButtonValueChanged(app, event)
            app.enable_950 = app.enable_950Button.Value;
        end

        % Value changed function: enable_1100Button
        function enable_1100ButtonValueChanged(app, event)
            app.enable_1100 = app.enable_1100Button.Value;
        end

        % Value changed function: FrequencyHzEditField_1100
        function FrequencyHzEditField_1100ValueChanged(app, event)
            app.parameters_1100.freq = app.FrequencyHzEditField_1100.Value;
        end

        % Value changed function: shutteropenmsEditField_1100
        function shutteropenmsEditField_1100ValueChanged(app, event)
            app.parameters_1100.msec_on = app.shutteropenmsEditField_1100.Value;
        end

        % Value changed function: stimdurationmsEditField_1100
        function stimdurationmsEditField_1100ValueChanged(app, event)
            app.parameters_1100.stim_duration = app.stimdurationmsEditField_1100.Value;
        end

        % Value changed function: delaymsEditField_1100
        function delaymsEditField_1100ValueChanged(app, event)
            app.parameters_1100.delay = app.delaymsEditField_1100.Value;
        end

        % Value changed function: delaymsEditField_950
        function delaymsEditField_950ValueChanged(app, event)
            app.parameters_950.delay = app.delaymsEditField_950.Value;
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            app.tman.start_trial();
            app.tman.end_trial();
            cla(app.UIAxes)
            app.FIREButton.Enable = "off";
            app.close_shutters();
            app.shutter_950.Enable = true;
            app.shutter_1100.Enable = true;

        end

        % Value changed function: shutter_950
        function shutter_950ValueChanged(app, event)
            value = app.shutter_950.Value;
            switch value
                case 'OPEN'
                    app.state_950 = true;
                case 'CLOSED'
                    app.state_950 = false;
            end
            app.update_shutters();
        end

        % Value changed function: shutter_1100
        function shutter_1100ValueChanged(app, event)
            value = app.shutter_1100.Value;
              switch value
                case 'OPEN'
                    app.state_1100 = true;
                case 'CLOSED'
                    app.state_1100 = false;
            end
            app.update_shutters();
        end

        % Value changed function: polarizer_950
        function polarizer_950ValueChanged(app, event)
            value = app.polarizer_950.Value;
            app.tman.modules.FiberPowerControl.hwp.moveto(value);
        end

        % Value changed function: polarizer_1100
        function polarizer_1100ValueChanged(app, event)
            value = app.polarizer_1100.Value;
            app.tman.modules.FiberPowerControl_1.hwp.moveto(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ShutterparametersPanel = uipanel(app.UIFigure);
            app.ShutterparametersPanel.Title = 'Shutter parameters';
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField_950.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditField_950ValueChanged, true);
            app.FrequencyHzEditField_950.Value = 10;
            app.shutteropenmsEditFieldLabel = uilabel(app.UIFigure);
            app.shutteropenmsEditFieldLabel.Text = 'shutter open (ms)';
            app.shutteropenmsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.shutteropenmsEditField_950.ValueChangedFcn = createCallbackFcn(app, @shutteropenmsEditField_950ValueChanged, true);
            app.shutteropenmsEditField_950.Value = 10;
            app.stimdurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.stimdurationmsEditFieldLabel.Text = 'stim duration (ms)';
            app.stimdurationmsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.stimdurationmsEditField_950.ValueChangedFcn = createCallbackFcn(app, @stimdurationmsEditField_950ValueChanged, true);
            app.stimdurationmsEditField_950.Value = 1000;
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.enable_950Button = uibutton(app.UIFigure, 'state');
            app.enable_950Button.ValueChangedFcn = createCallbackFcn(app, @enable_950ButtonValueChanged, true);
            app.enable_950Button.Text = 'enable';
            app.enable_1100Button = uibutton(app.UIFigure, 'state');
            app.enable_1100Button.ValueChangedFcn = createCallbackFcn(app, @enable_1100ButtonValueChanged, true);
            app.enable_1100Button.Text = 'enable';
            app.delaymsEditFieldLabel = uilabel(app.UIFigure);
            app.delaymsEditFieldLabel.Text = 'delay (ms)';
            app.delaymsEditField_950 = uieditfield(app.UIFigure, 'numeric');
            app.delaymsEditField_950.ValueChangedFcn = createCallbackFcn(app, @delaymsEditField_950ValueChanged, true);
            app.FrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.FrequencyHzEditField_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField_1100.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditField_1100ValueChanged, true);
            app.FrequencyHzEditField_1100.Value = 10;
            app.shutteropenmsEditField_2Label = uilabel(app.UIFigure);
            app.shutteropenmsEditField_2Label.Text = 'shutter open (ms)';
            app.shutteropenmsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.shutteropenmsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @shutteropenmsEditField_1100ValueChanged, true);
            app.shutteropenmsEditField_1100.Value = 10;
            app.stimdurationmsEditField_2Label = uilabel(app.UIFigure);
            app.stimdurationmsEditField_2Label.Text = 'stim duration (ms)';
            app.stimdurationmsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.stimdurationmsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @stimdurationmsEditField_1100ValueChanged, true);
            app.stimdurationmsEditField_1100.Value = 1000;
            app.delaymsEditField_2Label = uilabel(app.UIFigure);
            app.delaymsEditField_2Label.Text = 'delay (ms)';
            app.delaymsEditField_1100 = uieditfield(app.UIFigure, 'numeric');
            app.delaymsEditField_1100.ValueChangedFcn = createCallbackFcn(app, @delaymsEditField_1100ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '950';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1100';
            app.FIREButton = uibutton(app.UIFigure, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Enable = 'off';
            app.FIREButton.Text = 'FIRE';
            app.shutter_950 = uiswitch(app.UIFigure, 'toggle');
            app.shutter_950.Items = {'CLOSED', 'OPEN'};
            app.shutter_950.ValueChangedFcn = createCallbackFcn(app, @shutter_950ValueChanged, true);
            app.shutter_950.Value = 'CLOSED';
            app.shutter_1100 = uiswitch(app.UIFigure, 'toggle');
            app.shutter_1100.Items = {'CLOSED', 'OPEN'};
            app.shutter_1100.ValueChangedFcn = createCallbackFcn(app, @shutter_1100ValueChanged, true);
            app.shutter_1100.Value = 'CLOSED';
            app.PolarizerdegSpinnerLabel = uilabel(app.UIFigure);
            app.PolarizerdegSpinnerLabel.Text = 'Polarizer (deg)';
            app.polarizer_950 = uispinner(app.UIFigure);
            app.polarizer_950.ValueChangedFcn = createCallbackFcn(app, @polarizer_950ValueChanged, true);
            app.PolarizerdegSpinner_2Label = uilabel(app.UIFigure);
            app.PolarizerdegSpinner_2Label.Text = 'Polarizer (deg)';
            app.polarizer_1100 = uispinner(app.UIFigure);
            app.polarizer_1100.ValueChangedFcn = createCallbackFcn(app, @polarizer_1100ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberTesterAppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end