classdef PowerControllerSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        SHUTTER1030Button  matlab.ui.control.StateButton
        SHUTTER1100Button  matlab.ui.control.StateButton
        SHUTTER900Button   matlab.ui.control.StateButton
        Knob1030           matlab.ui.control.Knob
        Knob1100           matlab.ui.control.Knob
        Knob900            matlab.ui.control.Knob
        Label1030          matlab.ui.control.Label
        Label1100          matlab.ui.control.Label
        Label900           matlab.ui.control.Label
    end


    properties (Access = private)
        hwp900 % Description
        hwp1100
        hwp1030

        shutter900
        shutter1100
        shutter1030

        dq
    end

    methods (Access = private)

        function open(app, shutter_id)
            data = cat(2, app.shutter900, app.shutter1100, app.shutter1030);
            data(shutter_id) = 1;
            app.dq.write(data);
        end

        function close(app, shutter_id)
            data = cat(2, app.shutter900, app.shutter1100, app.shutter1030);
            data(shutter_id) = 0;
            app.dq.write(data);
        end

        function rotate(app, hwp, deg)
            hwp.moveto(deg);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dq = daq('ni');

            s = serialport("COM5", ...
                9600,...
                'ByteOrder', 'big-endian',...
                'Parity', 'none',...
                'StopBits', 1,...
                'DataBits', 8);
            s.configureTerminator('CR/LF');

            app.dq.addoutput('Dev1', 'port0/line5', 'Digital'); % 0/5: 920, 0/6: 1100
            app.hwp900 = ELL14(SerialInterface(s), 1, 'hwp'); % 0: 900, 1:1100 % might need both? idk
            app.shutter900 = 0;

            app.dq.addoutput('Dev1', 'port0/line4', 'Digital'); % 0/5: 920, 0/6: 1100
            app.hwp1100 = ELL14(SerialInterface(s), 2, 'hwp'); % 0: 900, 1:1100 % might need both? idk
            app.shutter1100 = 0;

            app.dq.addoutput('Dev1', 'port0/line6', 'Digital');
            app.hwp1030 = ELL14(SerialInterface(s), 3, 'hwp'); % 0: 900, 1:1100 % might need both? idk
            app.shutter1030 = 0;

            disp('Devices connected.')
        end

        % Value changed function: SHUTTER900Button
        function SHUTTER900ButtonValueChanged(app, event)
            value = app.SHUTTER900Button.Value;
            if value == 1
                app.open(1);
                app.shutter900 = 1;
                app.Label900.BackgroundColor = 'r';
                app.SHUTTER900Button.Text = 'OPEN';
            else
                app.close(1);
                app.shutter900 = 0;
                app.Label900.BackgroundColor = 'none';
                app.SHUTTER900Button.Text = 'CLOSED';
            end

        end

        % Value changed function: SHUTTER1100Button
        function SHUTTER1100ButtonValueChanged(app, event)
            value = app.SHUTTER1100Button.Value;
            if value == 1
                app.open(2);
                app.shutter1100 = 1;
                app.Label1100.BackgroundColor = 'r';
                app.SHUTTER1100Button.Text = 'OPEN';
            else
                app.close(2);
                app.shutter1100 = 0;
                app.Label1100.BackgroundColor = 'none';
                app.SHUTTER1100Button.Text = 'CLOSED';
            end
        end

        % Value changed function: SHUTTER1030Button
        function SHUTTER1030ButtonValueChanged(app, event)
            value = app.SHUTTER1030Button.Value;
            if value == 1
                app.open(3);
                app.shutter1030 = 1;
                app.Label1030.BackgroundColor = 'r';
                app.SHUTTER1030Button.Text = 'OPEN';
            else
                app.close(3);
                app.shutter1030 = 0;
                app.Label1030.BackgroundColor = 'none';
                app.SHUTTER1030Button.Text = 'CLOSED';
            end

        end

        % Value changed function: Knob1030
        function Knob1030ValueChanged(app, event)
            value = app.Knob1030.Value;
            app.rotate(app.hwp1030, value);
        end

        % Value changed function: Knob1100
        function Knob1100ValueChanged(app, event)
            value = app.Knob1100.Value;
            app.rotate(app.hwp1100, value);
        end

        % Value changed function: Knob900
        function Knob900ValueChanged(app, event)
            value = app.Knob900.Value;
            app.rotate(app.hwp900, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label900 = uilabel(app.GridLayout);
            app.Label900.Text = '900';
            app.Label1100 = uilabel(app.GridLayout);
            app.Label1100.Text = '1100';
            app.Label1030 = uilabel(app.GridLayout);
            app.Label1030.Text = '1030';
            app.Knob900 = uiknob(app.GridLayout, 'continuous');
            app.Knob900.Limits = [0 180];
            app.Knob900.ValueChangedFcn = createCallbackFcn(app, @Knob900ValueChanged, true);
            app.Knob1100 = uiknob(app.GridLayout, 'continuous');
            app.Knob1100.Limits = [0 180];
            app.Knob1100.ValueChangedFcn = createCallbackFcn(app, @Knob1100ValueChanged, true);
            app.Knob1030 = uiknob(app.GridLayout, 'continuous');
            app.Knob1030.Limits = [0 180];
            app.Knob1030.ValueChangedFcn = createCallbackFcn(app, @Knob1030ValueChanged, true);
            app.SHUTTER900Button = uibutton(app.GridLayout, 'state');
            app.SHUTTER900Button.ValueChangedFcn = createCallbackFcn(app, @SHUTTER900ButtonValueChanged, true);
            app.SHUTTER900Button.Text = 'CLOSED';
            app.SHUTTER1100Button = uibutton(app.GridLayout, 'state');
            app.SHUTTER1100Button.ValueChangedFcn = createCallbackFcn(app, @SHUTTER1100ButtonValueChanged, true);
            app.SHUTTER1100Button.Text = 'CLOSED';
            app.SHUTTER1030Button = uibutton(app.GridLayout, 'state');
            app.SHUTTER1030Button.ValueChangedFcn = createCallbackFcn(app, @SHUTTER1030ButtonValueChanged, true);
            app.SHUTTER1030Button.Text = 'CLOSED';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerControllerSimple

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end