classdef SimpleVoltageController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Label               matlab.ui.control.Label
        VOLTAGELabel        matlab.ui.control.Label
        SENDButton          matlab.ui.control.Button
        CMDVEditField       matlab.ui.control.NumericEditField
        CMDVEditFieldLabel  matlab.ui.control.Label
        SHUTOFFButton       matlab.ui.control.Button
        Label1030_2         matlab.ui.control.Label
        shutter_607         matlab.ui.control.StateButton
        Knob1030            matlab.ui.control.Knob
        Label1030           matlab.ui.control.Label
    end


    properties (Access = private)

        hwp607

        shutter607

        current_voltage

        dq
    end

    methods (Access = private)

        function open(app, shutter_id)
            data = cat(2, app.shutter607, app.current_voltage);
            data(shutter_id) = 1;
            app.dq.write(data);
        end

        function close(app, shutter_id)
            data = cat(2, app.shutter607, app.current_voltage);
            data(shutter_id) = 0;
            app.dq.write(data);
        end

        function cmd(app)
            data = cat(2, app.shutter607, app.current_voltage);
            app.dq.write(data);
        end
       
        function rotate(app, hwp, deg)
            hwp.set(deg);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dq = daq('ni');

            s = serialport("COM5", ...
                9600,...
                'ByteOrder', 'big-endian',...
                'Parity', 'none',...
                'StopBits', 1,...
                'DataBits', 8);
            s.configureTerminator('CR/LF');

            app.dq.addoutput('Dev1', 'port0/line6', 'Digital');
            app.hwp607 = ELL14(SerialInterface(s), 3, 'hwp'); % 0: 900, 1:1100 % might need both? idk
            app.shutter607 = 0;
            app.current_voltage = 0;
            app.Label.Text = '0';

            app.dq.addoutput('Dev1', 'ao1', 'Voltage');

            disp('Devices connected.')
        end

        % Value changed function: shutter_607
        function shutter_607ValueChanged(app, event)
            value = app.shutter_607.Value;
            if value == 1
                app.open(1);
                app.shutter607 = 1;
                app.Label1030.BackgroundColor = 'r';
                app.shutter_607.Text = 'OPEN';
            else
                app.close(1);
                app.shutter607 = 0;
                app.Label1030.BackgroundColor = 'none';
                app.shutter_607.Text = 'CLOSED';
            end

        end

        % Value changed function: Knob1030
        function Knob1030ValueChanged(app, event)
            value = app.Knob1030.Value;
            app.rotate(app.hwp607, value);
        end

        % Value changed function: CMDVEditField
        function CMDVEditFieldValueChanged(app, event)
            value = app.CMDVEditField.Value;
            if value < 3
                app.current_voltage = value;
                app.Label.Text = sprintf('%0.2f', app.current_voltage);
            end
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            app.cmd();
        end

        % Button pushed function: SHUTOFFButton
        function SHUTOFFButtonPushed(app, event)
            app.current_voltage = 0;
            app.Label.Text = 'OFF';
            app.cmd();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label1030 = uilabel(app.GridLayout);
            app.Label1030.Text = '607';
            app.Knob1030 = uiknob(app.GridLayout, 'continuous');
            app.Knob1030.Limits = [0 180];
            app.Knob1030.ValueChangedFcn = createCallbackFcn(app, @Knob1030ValueChanged, true);
            app.shutter_607 = uibutton(app.GridLayout, 'state');
            app.shutter_607.ValueChangedFcn = createCallbackFcn(app, @shutter_607ValueChanged, true);
            app.shutter_607.Text = 'CLOSED';
            app.Label1030_2 = uilabel(app.GridLayout);
            app.Label1030_2.Text = '1030';
            app.SHUTOFFButton = uibutton(app.GridLayout, 'push');
            app.SHUTOFFButton.ButtonPushedFcn = createCallbackFcn(app, @SHUTOFFButtonPushed, true);
            app.SHUTOFFButton.Text = 'SHUT OFF';
            app.CMDVEditFieldLabel = uilabel(app.GridLayout);
            app.CMDVEditFieldLabel.Text = 'CMD V';
            app.CMDVEditField = uieditfield(app.GridLayout, 'numeric');
            app.CMDVEditField.ValueChangedFcn = createCallbackFcn(app, @CMDVEditFieldValueChanged, true);
            app.SENDButton = uibutton(app.GridLayout, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.VOLTAGELabel = uilabel(app.GridLayout);
            app.VOLTAGELabel.Text = 'VOLTAGE:';
            app.Label = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleVoltageController

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end